classdef dynamic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        txtTab                matlab.ui.container.Tab
        TextArea_txt          matlab.ui.control.TextArea
        Label_4               matlab.ui.control.Label
        Button_txt            matlab.ui.control.Button
        TextArea_jiexi_ini    matlab.ui.control.TextArea
        Label_3               matlab.ui.control.Label
        Button_jiexi          matlab.ui.control.Button
        matTab                matlab.ui.container.Tab
        Button_mat            matlab.ui.control.Button
        TextArea_mat          matlab.ui.control.TextArea
        Label                 matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        Button_Dynamic_judge  matlab.ui.control.Button
        TextArea_judge_ini    matlab.ui.control.TextArea
        Label_2               matlab.ui.control.Label
        Button_ini            matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        UIAxes11              matlab.ui.control.UIAxes
        UIAxes10              matlab.ui.control.UIAxes
        UIAxes9               matlab.ui.control.UIAxes
        UIAxes8               matlab.ui.control.UIAxes
        UIAxes7               matlab.ui.control.UIAxes
        UIAxes6               matlab.ui.control.UIAxes
        UIAxes5               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes1               matlab.ui.control.UIAxes
        UIAxes_3D             matlab.ui.control.UIAxes
    end

% ----------------------------------------【自定义属性、变量】-----------------------------------------------------
    
    properties (Access = private)
        kaiguan=0;  % Description
        configData;  % 想要引用，直接用app.变量即可，除非需要初始化，才在这里定义。
        cc=0;
        file_path
        file_name
        data
        X_danwei
        D
        T
        AR
    end

% ----------------------------------------【自定义方法、函数】-----------------------------------------------------
    methods (Access = private)
        % 解析代码子函数

        %【解析代码函数】
        function AR=jiexiexchange(app,file_lujing)
                % 通道个数
            AVG_Ch_Num = 0;
            ADC_Ch_Num = 0;
        
            DISP_Ch_Num1 = 0;
            FFT_Ch_Num1 = 0;
            DISP_Ch_Num2 = 0;
            FFT_Ch_Num2 = 0;
        
            LF_I_Ch_Num = 0;
            MSR_VI_Ch_Num = 0;
            % 通道选择数组
            AVG_Ch_Sel = {};
            ADC_Ch_Sel = {};
        
            DISP_Ch_Sel1 = {};
            FFT_Ch_Sel1 = {};
        
            DISP_Ch_Sel2 = {};
            FFT_Ch_Sel2 = {};
        
            LF_I_Ch_Sel = {};
            MSR_VI_Ch_Sel = {};
            % DISP channel Initial
            DISP_Ch_Array1{1} = 'CH_AMP_DISP1';
            DISP_Ch_Array1{2} = 'CH_T_DISP1';
            DISP_Ch_Array1{3} = 'CH_PHA_JDG0';
            DISP_Ch_Array1{4} = 'CH_AMP_JDG0';
            DISP_Ch_Array1{5} = 'CHWAVE4M10M';
            DISP_Ch_Array1{6} = 'CH_COR_JDG';
        
            DISP_Ch_Array2{1} = 'CH_AMP_DISP0';
            DISP_Ch_Array2{2} = 'CH_T_DISP0';
            DISP_Ch_Array2{3} = 'CH_PHA_JDG1';
            DISP_Ch_Array2{4} = 'CH_AMP_JDG1';
            DISP_Ch_Array2{5} = 'CHWAVE8M48M';
        
            % MSR_Ch_Array{1} = 'CH_MSR_V';
            % MSR_Ch_Array{2} = 'CH_MSR_I';
            % save -append 选项
            jiexi_lujing=app.TextArea_jiexi_ini.Value;
            jiexi_lujing=jiexi_lujing{1};
            %% 带计量电流连续采集
            fid=fopen(jiexi_lujing);
            %% 
        
            %20210520-100半波-50M_直流电弧
            %D:\直流电弧数据\1115\快速采集111\连续特征值和-2-20、30-48M FFT配置文件-20221114-500半波-400M.ini
            %上面是快速采集2.5秒
            %20210520-10半波-400M.ini
        
            data = textscan(fid,'%s');
            IniParaCell=data{1,1};
        
            if~isempty(regexp(IniParaCell{6}, '64', 'once'))%通道使能
                % 通道特征值字节数
                AVG_Len = 4;   % PGA_Len = 4;
            %     AVG_Len = 64 + 4;   % PGA_Len = 4;
                ADC_Len = 51200;
                DISP_Len = 2;
                FFT_Len = 4;
                LF_I_Len = 4;
                MSR_VI_Len = 4;
            elseif~isempty(regexp(IniParaCell{6}, '128', 'once'))
                AVG_Len =4;   % PGA_Len = 4;
            %     AVG_Len = 64 + 4;   % PGA_Len = 4;
                ADC_Len = 51200;
        
                DISP_Len = 2;
                FFT_Len = 4;
                LF_I_Len = 4;
                MSR_VI_Len = 4;
            end
        
            for index = 35 : 1 :97
                if~isempty(regexp(IniParaCell{index}, 'true', 'once'))%通道使能
                    if(index  == 35)  %幅值平均值
                        AVG_Ch_Num = AVG_Ch_Num + 1;
                        AVG_Ch_Sel{AVG_Ch_Num} = 'CHAVG';
                    elseif(index <= 37)  %ADC
                        ADC_Ch_Num = ADC_Ch_Num + 1;
                        ADC_Ch_Sel{ADC_Ch_Num} = ['ADC',num2str(ADC_Ch_Num - 1)];
                    elseif(index <= 43)  %高频离散度1
                        DISP_Ch_Num1 = DISP_Ch_Num1 + 1;
                        DISP_Ch_Sel1{DISP_Ch_Num1} = DISP_Ch_Array1{index - 37};
        
                    elseif(index <= 52)  %FFT1
                        FFT_Ch_Num1 = FFT_Ch_Num1 + 1;
                        FFT_Ch_Sel1{FFT_Ch_Num1} = ['CH_FFT_',num2str(index-42),'M'];
        
                    elseif(index <= 57)  %高频离散度2
                        DISP_Ch_Num2 = DISP_Ch_Num2 + 1;
                        DISP_Ch_Sel2{DISP_Ch_Num2} = DISP_Ch_Array2{index - 52};
        
                    elseif(index <= 95)  %FFT2
                        FFT_Ch_Num2 = FFT_Ch_Num2 + 1;
                        FFT_Ch_Sel2{FFT_Ch_Num2} = ['CH_FFT_',num2str(index-47),'M'];
        
                    elseif(index <= 96)  %低频电流
                        continue;
        
                    elseif(index <= 97)  %计量电压电流
                        MSR_VI_Ch_Num = MSR_VI_Ch_Num + 1;
                        continue;
                    end
        
                end
            end
        
            % 半波个数
            BanBoNum = str2num(IniParaCell{5}(8:length(IniParaCell{5})));
        
%             dir_name=file_lujing;
            %dir_name="D:\直流电弧数据\2023\0703\直流采集交流\串口";
        
%             % 检查目录中是否存在已转换完成的matlab文件
%             fl=dirr(app,dir_name);
%             nl=length(fl);
        
            %调试数据顺序
            % for i=1:n
            % [~, sortedIndex] = sort([fl.isdir(i).datenum]); % 按照日期排序
            % sortedFileList = fileList(sortedIndex);      % 按照排序索引重新排列文件列表
            % fl.isdir(i)   =sortedFileList
            % end
        
%             cishu_mat=0;cishu_txt=0;
%             mat_name={};
%             txt_name={};
%             for i=1:nl
%                 chang(i)=length(fl(i).isdir);
%                 for j=1:chang(i)
%                     file = fullfile(fl(i).isdir(1).folder,fl(i).isdir(j).name);
%         
%                     mingzi=fl(i).isdir(j).name;
%                     if(regexp(mingzi,'.mat'))
%                         cishu_mat=cishu_mat+1;
%                         mat_name{cishu_mat,1}=file(1:end-4);
%                         mat_name{cishu_mat,2}=mingzi(1:end-4);
%         
%                     else
%                         cishu_txt=cishu_txt+1;
%                         txt_name{cishu_txt,1}=file(1:end-4);
%                         txt_name{cishu_txt,2}=mingzi(1:end-4);
%                     end
%                 end
%             end
%         
%             if length(mat_name)==0
%                 file_name=txt_name(:,2);
%                 file_dir=txt_name(:,1);
%             else
%                 file_name=setdiff(txt_name(:,2),mat_name(:,2));
%                 file_dir=setdiff(txt_name(:,1),mat_name(:,1));
%             end
            for i=1:1
                cishu=i
%                 filename=file_lujing
        
                    BanBoNum = str2num(IniParaCell{5}(8:length(IniParaCell{5})));
        
            %         BanBoNum = str2num(IniParaCell{5}(8:length(IniParaCell1{5})));


%                 file=[file_dir{i},'.txt'];
        
                %     a=textread(file,'%s')';
                file=file_lujing{1}
                fid = fopen(file);
                data = textscan(fid,'%s');
                a=data{1,1};
                fclose(fid);
        
                % %高频电弧特征值
        
                b = a;%高频电弧特征值
                Bytes_Length = length(b);
                valid_num=[];
                meter_valid_num=[];
                CHAVG=[];
                CH_PGA=[];
                ysAVG={};
                AVG_b = {};
                ADC_b = {};
                DISP1_b = {};
                FFT1_b = {};
                DISP2_b = {};
                FFT2_b = {};
                LF_I_b = {};
                MSR_VI_b = {};
        
                All_AVG_Bytes = 0;
                All_ADC_Bytes = 0;
                All_DISP_Bytes1 = 0;
                All_FFT_Bytes1 = 0;
                All_DISP_Bytes2 = 0;
                All_FFT_Bytes2 = 0;
                All_LF_I_Bytes = 0;
                All_MSR_VI_Bytes = 0;
                AllBytes = 0;
                %  ----------------------------
                for k1 = 1 : BanBoNum
                    % 每个半波特征值个数列表
                    b{end - 2 * BanBoNum + k1};
                    valid_num(k1) = hex2dec(b{end - 2 * BanBoNum + k1});
                    meter_valid_num(k1) = hex2dec(b{end - BanBoNum + k1});
                    %% 不同通道特征值特征值个数
                    AVG_Bytes	=   AVG_Len * AVG_Ch_Num;
                    ADC_Bytes	=   ADC_Len * ADC_Ch_Num;
        
                    DISP_Bytes1  = valid_num(k1) * DISP_Len * DISP_Ch_Num1;
                    FFT_Bytes1   = valid_num(k1) * FFT_Len * FFT_Ch_Num1;
                    DISP_Bytes2  = valid_num(k1) * DISP_Len * DISP_Ch_Num2;
                    FFT_Bytes2   = valid_num(k1) * FFT_Len * FFT_Ch_Num2;
                    LF_I_Bytes	=	valid_num(k1) * LF_I_Len * LF_I_Ch_Num;
                    MSR_VI_Bytes	=	meter_valid_num(k1) * MSR_VI_Len * MSR_VI_Ch_Num;
        
        
                    if(AVG_Bytes > 0)
                        AVG_b(All_AVG_Bytes + 1 : All_AVG_Bytes + AVG_Bytes)  =  b(AllBytes + 1 : ...
                            AllBytes + AVG_Bytes);
                    end
        
                    if(ADC_Bytes > 0)
                        ADC_b(All_ADC_Bytes + 1 : All_ADC_Bytes + ADC_Bytes)  =  b(AllBytes + AVG_Bytes + 1 : ...
                            AllBytes + AVG_Bytes + ADC_Bytes);
                    end
        
                    if(DISP_Bytes1 > 0)
                        DISP1_b(All_DISP_Bytes1 + 1 : All_DISP_Bytes1 + DISP_Bytes1) =  b(AllBytes + AVG_Bytes + ADC_Bytes + 1 : ...
                            AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1);
                    end
        
                    if(FFT_Bytes1 > 0)
                        FFT1_b(All_FFT_Bytes1 + 1 : All_FFT_Bytes1 + FFT_Bytes1)  =  b(AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + 1 : ...
                            AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1);
                    end
        
        
                    if(DISP_Bytes2 > 0)
                        DISP2_b(All_DISP_Bytes2 + 1 : All_DISP_Bytes2 + DISP_Bytes2) =  b(AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + 1 : ...
                            AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2);
                    end
        
        
                    if(FFT_Bytes2 > 0)
                        FFT2_b(All_FFT_Bytes2 + 1 : All_FFT_Bytes2 + FFT_Bytes2) =  b(AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2 + 1 : ...
                            AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2 + FFT_Bytes2);
                    end
        
                    if(LF_I_Bytes > 0)
                        LF_I_b(All_LF_I_Bytes + 1 : All_LF_I_Bytes + LF_I_Bytes) =  b(AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2 + FFT_Bytes2 + 1 : ...
                            AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2 + FFT_Bytes2 + LF_I_Bytes);
                    end
        
                    if(MSR_VI_Bytes > 0)
                        MSR_VI_b(All_MSR_VI_Bytes + 1 : All_MSR_VI_Bytes + MSR_VI_Bytes) =  b(AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2 + FFT_Bytes2 + LF_I_Bytes + 1 : ...
                            AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2 + FFT_Bytes2 + LF_I_Bytes + MSR_VI_Bytes);
                    end
        
                    All_AVG_Bytes = All_AVG_Bytes + AVG_Bytes;
                    All_ADC_Bytes = All_ADC_Bytes + ADC_Bytes;
        
                    All_DISP_Bytes1 = All_DISP_Bytes1 + DISP_Bytes1;
                    All_FFT_Bytes1 = All_FFT_Bytes1 + FFT_Bytes1;
                    All_DISP_Bytes2 = All_DISP_Bytes2 + DISP_Bytes2;
                    All_FFT_Bytes2 = All_FFT_Bytes2 + FFT_Bytes2;
                    All_LF_I_Bytes = All_LF_I_Bytes + LF_I_Bytes;
                    All_MSR_VI_Bytes = All_MSR_VI_Bytes + MSR_VI_Bytes;
        
                    AllBytes = AllBytes + AVG_Bytes + ADC_Bytes + DISP_Bytes1 + FFT_Bytes1 + DISP_Bytes2 + FFT_Bytes2 + LF_I_Bytes + MSR_VI_Bytes;
                end
        
        
                if(AVG_Bytes > 0)
                    AVG_b_length = length(AVG_b);
                    for i0  = 1: AVG_b_length/4
                        ysAVG{i0} = [AVG_b{4*i0},AVG_b{4*i0 - 1},AVG_b{4*i0 - 2},AVG_b{4*i0 - 3}];
        
                    end
                    yAVG = hex2dec(ysAVG);
                    yAVG=yAVG';
                    for ii0 = 1:length(yAVG)/17
                        CHAVG(16 * (ii0 - 1) + 1  :16 * (ii0 - 1) + 16) = yAVG(17 * (ii0 - 1) + 1  :17 * (ii0 - 1) + 16);
                        CH_PGA(ii0) = yAVG(17 * (ii0 - 1) + 17) - 9;
                    end
        
%                     if(exist([file(1:end-4),'.mat']))
%                         save([file(1:end-4),'.mat'],'CHAVG','-append');
%                     else
%                         save([file(1:end-4),'.mat'],'CHAVG');
%                     end
                    %         end
        
                    % PGA
%                     if(exist([file(1:end-4),'.mat']))
%                         save([file(1:end-4),'.mat'],'CH_PGA','-append');
%                     else
%                         save([file(1:end-4),'.mat'],'CH_PGA');
%                     end
                end
                %             end
        
                %------------------ADC数据解析待确认--------------------------------
                %     if(ADC_Bytes > 0)
                %         ADC_b_length = length(ADC_b);
                %         for i1  = 1: ADC_b_length/2
                %             ysADC{i1} = [ADC_b{2 * i1},ADC_b{2 * i1 - 1}];
                %             yADC(i0) = hex2dec(ysADC{i1});
                %         end
                %         for j = 1:length(yADC)
                %             if(yADC(j) > 2^11)
                %                 yADC(j) = mod(yADC(j),2^11) - 2^11;
                %             else
                %                 yADC(j) = yADC(j);
                %             end
                %         end
                %
                %         xADC = reshape(yADC,ADC_b_length/(2*BanBoNum),BanBoNum);
                %
                %         for l1 = 1:ADC_b_length/(ADC_Len*BanBoNum)
                %             for k1 = 1: BanBoNum
                %                 xADC1((k1-1)*(ADC_Len/2)+1:(k1-1)*(ADC_Len/2)+(ADC_Len/2),l1)  = xADC((l1-1)*(ADC_Len/2)+1:(l1-1)*(ADC_Len/2)+(ADC_Len/2),k1);
                %             end
                %         end
                %         xADC2 = xADC1';
                %
                %         ADC1 = xADC2(1,:);
                %         ADC2 = xADC2(2,:);
                %         if(exist([file(1:end-4),'.mat']))
                %             save([file(1:end-4),'.mat'],'ADC1','ADC2','-append');
                %         else
                %             save([file(1:end-4),'.mat'],'ADC1','ADC2');
                %         end
                %     end
        
                if(DISP_Bytes1 > 0)
                    ysDISP1 = {};
                    yDISP1 = [];
                    DISP1_b_length = length(DISP1_b);
        
        
                    DISP1_b_reshpae=reshape(DISP1_b,2,DISP1_b_length/2);
                    yDISP1 = hex2dec(DISP1_b_reshpae);
                    yDISP1_tmpppp=yDISP1(1:2:end)*1+yDISP1(2:2:end)*16^2;
                    yDISP1=yDISP1_tmpppp';
        
        
                    for ch = 1 : DISP_Ch_Num1
                        eval([DISP_Ch_Sel1{ch},'=[];']);
                    end
                    AllDisp1ChNum = 0;
                    for k1 = 1 : BanBoNum
        
                        DISP1_s_Bytes_Tmp(k1) = valid_num(k1) * DISP_Len / 2;
                        DISP1_Bytes_Tmp(k1)	=	DISP1_s_Bytes_Tmp(k1) * DISP_Ch_Num1 ;
        
                        for ch = 1 : DISP_Ch_Num1
                            eval([DISP_Ch_Sel1{ch},'= [',DISP_Ch_Sel1{ch},',  yDISP1(AllDisp1ChNum + DISP1_s_Bytes_Tmp(k1) * (ch - 1 ) + 1 : AllDisp1ChNum + DISP1_s_Bytes_Tmp(k1) * (ch - 1 ) + valid_num(k1))];']);
                        end
                        AllDisp1ChNum =  AllDisp1ChNum + DISP1_Bytes_Tmp(k1);
                    end
        
%                     for ch = 1 : DISP_Ch_Num1
%                         if(exist([file(1:end-4),'.mat']))
%                             save([file(1:end-4),'.mat'],DISP_Ch_Sel1{ch},'-append');
%                         else
%                             save([file(1:end-4),'.mat'],DISP_Ch_Sel1{ch});
%                         end
%                     end
        
                    if(exist('CH_COR_JDG'))
                        cor_len = length(CH_COR_JDG);
                        for CorNum = 1:cor_len
                            if(CH_COR_JDG(CorNum) > 16)
                                CH_COR_JDG(CorNum) = -(CH_COR_JDG(CorNum) - 32 + 1);
                            end
                        end
%                         if(exist([file(1:end-4),'.mat']))
%                             save([file(1:end-4),'.mat'],'CH_COR_JDG','-append');
%                         else
%                             save([file(1:end-4),'.mat'],'CH_COR_JDG');
%                         end
                    end
        
        
                end
        
                if(FFT_Bytes1 > 0)
                    ysFFT1 = {};
                    yFFT1 = [];
                    FFT1_b_length = length(FFT1_b);
        
                    FFT1_b_reshpae=reshape(FFT1_b,4,FFT1_b_length/4);
                    yFFT1 = hex2dec(FFT1_b_reshpae);
                    yFFT1_tmpppp=yFFT1(1:4:end)*1+yFFT1(2:4:end)*16^2+yFFT1(3:4:end)*16^4+yFFT1(4:4:end)*16^6;
                    yFFT1=yFFT1_tmpppp';
        
                    for ch = 1 : FFT_Ch_Num1
                        eval([FFT_Ch_Sel1{ch},'=[];']);
                    end
                    AllFFT1ChNum = 0;
                    for k1 = 1 : BanBoNum
        
                        for ch = 1 : FFT_Ch_Num1
                            eval([FFT_Ch_Sel1{ch},'= [',FFT_Ch_Sel1{ch},',  yFFT1(AllFFT1ChNum + valid_num(k1) * (ch - 1 ) + 1 : AllFFT1ChNum + valid_num(k1) * (ch - 1 ) + valid_num(k1))];']);
                        end
                        AllFFT1ChNum =  AllFFT1ChNum + valid_num(k1) * FFT_Ch_Num1;
                    end
        
        
                    for ch = 1 : FFT_Ch_Num1
%                         if(exist([file(1:end-4),'.mat']))
%                             save([file(1:end-4),'.mat'],FFT_Ch_Sel1{ch},'-append');
%                         else
%                             save([file(1:end-4),'.mat'],FFT_Ch_Sel1{ch});
%                         end
                    end
        
                end
        
                if(DISP_Bytes2 > 0)
                    ysDISP2 = {};
                    yDISP2 = [];
                    DISP2_b_length = length(DISP2_b);
        
                    DISP2_b_reshpae=reshape(DISP2_b,2,DISP2_b_length/2);
                    yDISP2 = hex2dec(DISP2_b_reshpae);
                    yDISP2_tmpppp=yDISP2(1:2:end)*1+yDISP2(2:2:end)*16^2;
                    yDISP2=yDISP2_tmpppp';
        
        
                    for ch = 1 : DISP_Ch_Num2
                        eval([DISP_Ch_Sel2{ch},'=[];']);
                    end
                    AllDisp2ChNum = 0;
                    for k1 = 1 : BanBoNum
        
                        DISP2_s_Bytes_Tmp(k1) = valid_num(k1) * DISP_Len / 2;
                        DISP2_Bytes_Tmp(k1)	=	DISP2_s_Bytes_Tmp(k1) * DISP_Ch_Num2 ;
        
                        for ch = 1 : DISP_Ch_Num2
                            eval([DISP_Ch_Sel2{ch},'= [',DISP_Ch_Sel2{ch},',  yDISP2(AllDisp2ChNum + DISP2_s_Bytes_Tmp(k1) * (ch - 1 ) + 1 : AllDisp2ChNum + DISP2_s_Bytes_Tmp(k1) * (ch - 1 ) + valid_num(k1))];']);
                        end
                        AllDisp2ChNum =  AllDisp2ChNum + DISP2_Bytes_Tmp(k1);
                    end
        
                    for ch = 1 : DISP_Ch_Num2
%                         if(exist([file(1:end-4),'.mat']))
%                             save([file(1:end-4),'.mat'],DISP_Ch_Sel2{ch},'-append');
%                         else
%                             save([file(1:end-4),'.mat'],DISP_Ch_Sel2{ch});
%                         end
                    end
        
                end
        
        
                if(FFT_Bytes2 > 0)
                    ysFFT2 = {};
                    yFFT2 = [];
                    FFT2_b_length = length(FFT2_b);
        
                    FFT2_b_reshpae=reshape(FFT2_b,4,FFT2_b_length/4);
                    yFFT2 = hex2dec(FFT2_b_reshpae);
                    yFFT2_tmpppp=yFFT2(1:4:end)*1+yFFT2(2:4:end)*16^2+yFFT2(3:4:end)*16^4+yFFT2(4:4:end)*16^6;
                    yFFT2=yFFT2_tmpppp';
        
        
                    for ch = 1 : FFT_Ch_Num2
                        eval([FFT_Ch_Sel2{ch},'=[];']);
                    end
                    AllFFT2ChNum = 0;
                    for k1 = 1 : BanBoNum
        
                        for ch = 1 : FFT_Ch_Num2
        
                            eval([FFT_Ch_Sel2{ch},'= [',FFT_Ch_Sel2{ch},',  yFFT2(AllFFT2ChNum + valid_num(k1) * (ch - 1 ) + 1 : AllFFT2ChNum + valid_num(k1) * (ch - 1 ) + valid_num(k1))];']);
                        end
                        AllFFT2ChNum =  AllFFT2ChNum + valid_num(k1) * FFT_Ch_Num2;
                    end
        
        
%                     for ch = 1 : FFT_Ch_Num2
%                         if(exist([file(1:end-4),'.mat']))
%                             save([file(1:end-4),'.mat'],FFT_Ch_Sel2{ch},'-append');
%                         else
%                             save([file(1:end-4),'.mat'],FFT_Ch_Sel2{ch});
%                         end
%                     end
        
                end
        
                if(LF_I_Bytes > 0)
                    ysLF_I = {};
                    yLF_I = [];
                    LF_I_b_length = length(LF_I_b);
        
                    LF_I_b_reshpae=reshape(LF_I_b,4,LF_I_b_length/4);
                    yLF_I = hex2dec(LF_I_b_reshpae);
                    yLF_I_tmpppp=yLF_I(1:4:end)*1+yLF_I(2:4:end)*16^2+yLF_I(3:4:end)*16^4+yLF_I(4:4:end)*16^6;
                    yLF_I=yLF_I_tmpppp';
        
        
                    for j = 1:length(yLF_I)
                        if(yLF_I(j) > 2^23)
                            yLF_I(j) = mod(yLF_I(j),2^23) - 2^23;
                        else
                            yLF_I(j) = yLF_I(j);
                        end
                    end
                    CHLF_I = yLF_I;
%                     if(exist([file(1:end-4),'.mat']))
%                         save([file(1:end-4),'.mat'],'CHLF_I','-append');
%                     else
%                         save([file(1:end-4),'.mat'],'CHLF_I');
%                     end
                end
        
        
                if(MSR_VI_Bytes > 0)
                    disp('2');
                    ysMSR_VI = {};
                    yMSR_VI = [];
                    b=0;
                    MSR_VI_b_length = length(MSR_VI_b);
        
                    MSR_VI_b_reshpae=reshape(MSR_VI_b,4,MSR_VI_b_length/4);
                    yMSR_VI = hex2dec(MSR_VI_b_reshpae);
                    yMSR_VI_tmpppp=yMSR_VI(1:4:end)*1+yMSR_VI(2:4:end)*16^2+yMSR_VI(3:4:end)*16^4+yMSR_VI(4:4:end)*16^6;
                    yMSR_VI=yMSR_VI_tmpppp';
            %断点处(x:4:end)改为(x:2:end)，只保留前两个；可以得到计量数据原始值
            %采集程序存在问题，5V基准传输有问题，数据传输过多，我回去调整。
        
                    % 二进制补码装换
                    for j = 1:length(yMSR_VI)
                        if(yMSR_VI(j) > 2^23)
                            yMSR_VI(j) = mod(yMSR_VI(j),2^23) - 2^23;
                        else
                            yMSR_VI(j) = yMSR_VI(j);
                        end
                    end
            %         %电压电流穿插解析
            %         CH_MSR_V =  yMSR_VI(1:2:end - 1);
            %         CH_MSR_I =  yMSR_VI(2:2:end);
            % 8.16修改-pc
                    CH_MSR_V =  yMSR_VI(1:2:end - 1);  % 无用 
            %         CH_MSR_I = (yMSR_VI(1:1:end)/78-2078.9808)./82.9353;%第三版线圈参数 (-B)/A
                    CH_MSR_I = (yMSR_VI(1:1:end)/78-2056)./27.47;%第三版线圈参数 (-B)/A
                    a=0;
%                     if(exist([file(1:end-4),'.mat']))
%                         save([file(1:end-4),'.mat'],'CH_MSR_V','CH_MSR_I','-append');
%                     else
%                         save([file(1:end-4),'.mat'],'CH_MSR_V','CH_MSR_I');
%                     end
                end
            yFFT1=reshape(yFFT1,[],BanBoNum);
            yFFT1=yFFT1';
            yFFT2=reshape(yFFT2,[],BanBoNum);
            yFFT2=yFFT2';
            AR=[yFFT1,yFFT2,CH_MSR_I'];
   
            
%             save([file(1:end-4),'.mat'],'valid_num','meter_valid_num','-append');
            end
        end
        %--------------------------------------------------------
        %--------------------------------------------------------
        % 【函数说明：读取配置文件】
        function config=read_ini_config(app,filename)
            fid = fopen(filename, 'r', 'n', 'UTF-8');  % 打开配置文件
            config = struct();  % 创建一个结构体存储配置
            current_section = '';  % 当前段落
            while ~feof(fid)
                % 从文件中读取一行
                line = fgetl(fid);
                
                % 检查行是否为空或以 # 开头（表示注释）
                if isempty(line) || startsWith(line, '#')
                    continue;  % 忽略空行和注释行
                end
                
                % 检查行是否以方括号包裹（表示段落）
                if startsWith(line, '[') && endsWith(line, ']')
                    current_section = strtrim(line(2:end-1));  % 去除方括号并去除首尾空格
                    config.(current_section) = struct();  % 创建新的段落
                    continue;
                end
                
                % 如果行末尾包含 "..."
                while endsWith(line, '...')
                    % 移除行末尾的 "..."
                    line = strtrim(line(1:end-3));
                    
                    % 读取下一行
                    next_line = fgetl(fid);
                    
                    % 去除下一行的换行符
                    next_line = strtrim(next_line);
                    
                    % 拼接当前行和下一行
                    line = [line,next_line];
                end
                
                % 解析键值对
                [key_value_str, comment] = strtok(line, '#');  % 使用#将键值对和注释分离
                [key, value] = strtok(key_value_str, '=');  % 使用等号分割键值对
                key = strtrim(key);  % 去除键的首尾空格
        
                % 检查键名是否为空字符串
                if isempty(key)
                    continue;  % 忽略无效的键值对
                end
        
                % 去除值的首尾空格，并去掉等号
                value = strtrim(value(2:end));
                
                % 如果值是一个以方括号包围的数组
                if startsWith(value, '[') && endsWith(value, ']')
                    value = strrep(strrep(value, ']', ''), '[', '');  % 去除方括号
                    value = strtrim(value);  % 去除值的首尾空格
                    
                    % 按逗号分割值，并将结果存储为数值型数组
                    value = strsplit(value, ',');
                    value = cellfun(@str2double, value);
        %             value = value(:);  % 将数组转换为列向量
                else
                    value=str2num(value);
                end
        
                % 存储键值对到当前段落中
                config.(current_section).(key) = value;
            end
                fclose(fid);  % 关闭文件
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_mat
        function Button_matPushed(app, event)

            % 清空多个图形当前的坐标轴
            axesList = [app.UIAxes1, app.UIAxes2, app.UIAxes3, app.UIAxes4, ...
                    app.UIAxes5, app.UIAxes6, app.UIAxes7, app.UIAxes8, ...
                    app.UIAxes9, app.UIAxes10,app.UIAxes11,app.UIAxes_3D];
            app.file_path=[];
            for i = 1:numel(axesList)
                cla(axesList(i));
            end
            
            [file, path] = uigetfile('*.mat', '选择MAT文件');
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            app.kaiguan = 1;
            app.file_path  = fullfile(path, file);
            app.file_name = file;
            app.TextArea_mat.Value = app.file_path;
            matdata_temp=[];
%             try
            % 加载 .mat 文件,将数据存放进app.data
%                 a=load('-ASCII', app.file_path);
            a=load(app.file_path);
            disp(['Loaded: ' app.file_path]);

            for j= 2:48
                eval(['temp=a.CH_FFT_',num2str(j),'M;']);
                temp=double((temp'));
                matdata_temp=[matdata_temp,temp];
                % 处理你的操作，例如打印文件名或处理数据
            end
                
            try
                app.data=[matdata_temp,a.CH_MSR_I'];
            catch
                % 显示错误导入的消息
                msg = sprintf('配置文件 "%s" 导入不匹配', file);
                uialert(app.UIFigure, msg, '导入不匹配,请重新导入', 'Icon', 'error');
            end
            figure(app.UIFigure);  % 不下沉

        end

        % Callback function
        function Button_plot_3DPushed(app, event)
            % 在这里实现点击按钮时触发的操作
            % 例如，读取数据并在 "PlotAxes" 上绘制图形
            % 清空图形窗口
             delete app  % 销毁窗口，终止程序的运行
        end

        % Callback function: Button_ini, TextArea_judge_ini
        function ButtonPushed(app, event)
            % 显示文件选择对话框以选择.ini配置文件
            try
                [filename, pathname] = uigetfile('*.ini', '选择配置文件');
                
                % 检查用户是否选择了文件
                if isequal(filename, 0)
                    disp('用户取消了选择 .ini 配置文件');
                else
                    % 构建完整的文件路径
                    fullFilePath = fullfile(pathname, filename);
            
                    % 调用读取.ini配置文件的函数，并传递文件路径
                    app.configData = read_ini_config(app,fullFilePath);
     
                    % 从configData提取所需的配置信息并进行处理
                    % ...
                    % 显示成功导入的消息
                    msg = sprintf('配置文件 "%s" 已成功导入', filename);
                    uialert(app.UIFigure, msg, '导入成功', 'Icon', 'success');
                                           
                    % 访问配置项和执行其他操作...
                    app.TextArea_judge_ini.Value = filename;
                    % 配置文件信息
                    app.D=app.configData.Section1.D;
                    app.T=app.configData.Section1.T;
                    if app.D==1
                        app.T=1;
                        app.X_danwei='点数';
                    else
                        app.X_danwei='t/ms';
                    end
                end
                figure(app.UIFigure);
            catch error
                % 将错误信息写入日志文件
                fid = fopen('error_log2.txt', 'a');
                fprintf(fid, '%s\n', error.message);
                fclose(fid);
            end
        end

        % Button down function: UIAxes1
        function UIAxes1ButtonDown(app, event)

        end

        % Value changed function: TextArea_mat
        function TextArea_matValueChanged(app, event)
            value = app.TextArea_mat.Value; % 不能删
            
        end

        % Button pushed function: Button_Dynamic_judge
        function Button_Dynamic_judgePushed(app, event)
            try
                totalTasks = 2; % 总任务数量
                completedTasks = 0; % 已完成任务数量
                % 创建并显示进度条对话框
                progressDlg = uiprogressdlg(app.UIFigure, 'Title', '请等待...', 'Message', '正在处理数据...');
                % h = waitbar(0, '正在处理数据...'); 
                
                judge_window=app.configData.Section1.judge_window;
                total_window=app.configData.Section1.total_window;
                power_basic_window=app.configData.Section1.power_basic_window;
                threshold1 =app.configData.Section1.threshold1;
                threshold1_selection_window=app.configData.Section1.threshold1_selection_window;   
                threshold2  =app.configData.Section1.threshold2;
                threshold2_selection_window=app.configData.Section1.threshold2_selection_window;    
                threshold3_switch=app.configData.Section1.threshold3_switch;  
                threshold3 =app.configData.Section1.threshold3;
                threshold4_switch=app.configData.Section1.threshold4_switch;  
                threshold4=app.configData.Section1.threshold4; 
                threshold5=app.configData.Section1.threshold5;
                num_threshold=app.configData.Section1.num_threshold;
                v=app.configData.Section1.v;
              
                % ----------------------------------------------
                
                %% 每个mat数据准备完毕，----等待判别主流程
                data_length=length(app.data);  % 数据长度
                % 基准更新标志
                basic_update_count=-1;     % 基准更新初始值-1
                basic_update_switch=1;     % 基准更新开关1
                % 获取基准--并复制所需个数
                cc=8;
                aa=power_basic_window;
                bb=9;
                power_basic=mean(app.data(1:power_basic_window,:));       % 上电定基准初始化--10个点求平均值1*48
                power_basic=repmat(power_basic,power_basic_window,1); % 方便视图-->复制成基准窗口个数：power_basic_window*48
                % 基准窗口--结果初始化--全0
                basic_window_initialization=zeros(1,power_basic_window);
                % 所有基准--1个mat
                all_basic=[];
                % 判别窗口
                discrimination_window=[];
                one_mat_one_cycle_judge_result=[];  %单周期判别结果
                one_mat_many_cycle_judge_result=[]; %多周期判别结果
                % 判别结果初始化
                result=zeros(data_length-power_basic_window,num_threshold);   % 以周期为单位，每个周期，n个阈值出n个结果，定义判别结果矩阵。
                for k = power_basic_window+1:data_length
                    % 【基准更新】
                    if basic_update_switch==1
                        Power_basic=mean(app.data(k-power_basic_window:k-1,:));
                        basic_update_count=basic_update_count+1;
                    end
                    all_basic=[all_basic;Power_basic];  % 每次基准汇总；
                    ever_cycle_data = app.data(k,:);        % 获得每次当前数据；
                    % 【判据一：各单频点--基准+固定值】---2-26M
                    view_channel = find(threshold1>0);
                    use_channel=view_channel; % 序号0代表2M,依次类推
                    comparison_result1 = sum((ever_cycle_data(use_channel) > Power_basic(use_channel)+threshold1(use_channel)));  % 各个通道比较
                    if comparison_result1>threshold1_selection_window     % 单通道过设定-窗口判别-25选2
                        result(k-power_basic_window,1)=1;
                    end
                    % 【判据二：各单频点--基准*固定倍数】---2-26M
                    view_channel = find(threshold2>0);
                    use_channel=view_channel; % 序号0代表2M,依次类推
                    comparison_result2 = sum((ever_cycle_data(use_channel) > Power_basic(use_channel).*threshold2(use_channel))); % 各个通道比较
                    if comparison_result2>threshold2_selection_window     % 单通道过设定-窗口判别-25选2
                        result(k-power_basic_window,2)=1;
                    end
                    % 【判据三：各频点求和--30-48M求和基准+固定值】---30-48M
                    view_channel=find(threshold3_switch>0);
                    use_channel=view_channel;
                    comparison_result3 = sum(ever_cycle_data(use_channel)) > sum(Power_basic(use_channel)) + threshold3; % 通道求和比较
                    if comparison_result3==1     % 判别
                        result(k-power_basic_window,3)=1;
                    end
                    % 【判据四：无量纲求和--sum(各个通道数值/各个通道基准)】---10-18M
                    view_channel=find(threshold4_switch>0);
                    use_channel=view_channel;
    %                 A=sum(ever_cycle_data(use_channel);
                    
                    comparison_result4 = double(sum(ever_cycle_data(use_channel))./double(Power_basic(use_channel))) > threshold4; % 通道求和比较
                    if comparison_result4==1     % 判别
                        result(k-power_basic_window,4)=1;
                    end
                    % 【判据五：电流值--sum(各个通道数值/各个通道基准)】
                    use_channel=48; % I
                    comparison_result5 = ever_cycle_data(use_channel) < Power_basic(use_channel).*threshold5; % 通道求和比较
                    if comparison_result5==1     % 判别
                        result(k-power_basic_window,5)=1;
                    end
                    
                    % 【单cycle5个阈值判别结果】
                    connection_result=result(k-power_basic_window,:);  % 拼接成单cycle
                    biaoji=sum(connection_result); % 单cycle 5判据求和
                    if biaoji==5
                        cycle_result=1;   % 单cycle判别结果为电弧
                    else
                        cycle_result=0;   % 单cycle判别结果为正常
                    end
                    one_mat_one_cycle_judge_result=[one_mat_one_cycle_judge_result,cycle_result];  % mat所有单cycle判别结果收集
                    % 【基准窗口--序列结果】
                    basic_window_initialization=[basic_window_initialization(2:power_basic_window),cycle_result];
                    if sum(basic_window_initialization)>0
                        basic_update_switch=0;
                    else
                        basic_update_switch=1;
                    end
                    %        ----------------------------------------------------------------------------------
                    % 【判别窗口--序列结果】
                    discrimination_window=[discrimination_window,cycle_result];
                    if length(discrimination_window)==total_window    % 如果判别窗口满了，则--总体判断+窗口更新
                        % 【总体判别】
                        if sum(discrimination_window)==judge_window
                            judge_result=1;     % mat判为电弧
                        else
                            judge_result=0;     % mat判为正常
                        end
                        one_mat_many_cycle_judge_result=[one_mat_many_cycle_judge_result,judge_result]; %one_mat各点结果汇总；
    %                     % 【总体输出】
    %                     if contains(app.file_name,'dh') && judge_result==1
    %                         disp('成功检测到dh');
    %                     elseif contains(app.file_name,'dh') && judge_result==0
    %                         disp('漏判为normal');
    %                     elseif contains(app.file_name,'dnormal') && judge_result==0
    %                         disp('成功检测到normal');
    %                     elseif contains(app.file_name,'dnormal') && judge_result==1
    %                         disp('误判为dh');
    %                     end
                        % 【滑窗更新】
                        discrimination_window=discrimination_window(2:total_window);   % 滑窗更新
                    end
                end
                % 执行任务
                completedTasks = completedTasks + 1; 
                % 更新进度条的值
                progressDlg.Value = completedTasks / totalTasks;
                % 暂停一段时间以演示进度条效果
                pause(0.3);
                
                
                % 【单mat各个判据结果汇总】
                forword_judge=zeros(power_basic_window,num_threshold);%判别之前的结果初始化
                one_mat_judge_all=[forword_judge;result];
                one_mat_judge1=one_mat_judge_all(:,1)';%判据1结果
                one_mat_judge2=one_mat_judge_all(:,2)';%判据2结果
                one_mat_judge3=one_mat_judge_all(:,3)';%判据3结果
                one_mat_judge4=one_mat_judge_all(:,4)';%判据4结果
                one_mat_judge5=one_mat_judge_all(:,5)';%判据5结果
                one_mat_one_cycle_judge_result=[zeros(1,power_basic_window),one_mat_one_cycle_judge_result];% 总单cycle结果--1个cycle1次
                one_mat_basic_all=[power_basic;all_basic];   % 总基准滑窗；
                one_mat_many_cycle_judge_result=[zeros(1,power_basic_window+total_window-1),one_mat_many_cycle_judge_result]; % 总判别结果--10个cycle1次；
                
                %-----------------------------------------------
                %% 【每个mat数据准备完毕，----先画各通道二维对比图】
                ALL_DATA=app.data(:,1:47);
                sort_ALL_DATA=sort(ALL_DATA);
                normal_data=mean(sort_ALL_DATA(1:50,1:47));
                dh_data=mean(sort_ALL_DATA(end-100:end,1:47));
                bizhi=dh_data./normal_data;                        
                % 画图
                channel_num=2:48;
                % yyaxis(app.UIAxes11,'left');
                ylabel(app.UIAxes11,'幅值平均值');
                bar(app.UIAxes11,channel_num,[normal_data;dh_data],'grouped');
                drawnow;
                hold on;  % 设置保持图形，以便在后面添加折线图
                % 创建右侧纵坐标轴
                yyaxis(app.UIAxes11,'right');   
                plot(app.UIAxes11,channel_num,bizhi);
                yyaxis(app.UIAxes11,'right');   
                close(1);
                ylabel(app.UIAxes11,'dh/normal比值');
                legend(app.UIAxes11,'nor', 'dh', 'dh/normal');
                xlim(app.UIAxes11,[1, length(channel_num)]);       %--X轴时间第二处更改：5ms;    xlim([1, data_length*5]); 
                title(app.UIAxes11,'FFT2M\_48M-nor\_dh对比');
                xlabel(app.UIAxes11,'频率/M');           
                
                %-----------------------------------------------
                %-----------------------------------------------
    
    
                %% 【每个mat数据准备完毕，----先画三维图】
                % 创建三维网格
                Z =app.data(:,1:47)';
                dot_len=length(Z);
                [X, Y] = meshgrid(1:app.T:dot_len*app.T, 1:47);
                %-----------------------------------------------
                %-----------------------------------------------            
                % 创建一个新的 figure 窗口
                % 根据Z轴值计算颜色
                h_3D = surf(app.UIAxes_3D, X, Y, Z);
                % 设置坐标轴标签
                xlabel(app.UIAxes_3D,app.X_danwei);
                ylabel(app.UIAxes_3D,'频率通道/M');
                zlabel(app.UIAxes_3D,[num2str(app.T),'ms幅值平均值']);
                xlim(app.UIAxes_3D,[1,dot_len*app.T]);
                % 添加标题
                title(app.UIAxes_3D,'三维频谱图--normal-dh');
                % 隐藏边界线
                set(h_3D, 'EdgeColor', 'none');
    %             % 调整视角
    %             view(45, 60)
                % % 统一制定表面颜色--不需要
                % set(h, 'FaceColor', [0.2, 0.25, 0.5])
                % 加载渐变色
                colormap(app.UIAxes_3D,'jet');
                colorbar(app.UIAxes_3D) %显示颜色条
                % 调整渐变色的变化范围
                zmin = min(Z(:)); % 最小值
                zmax = max(Z(:)); % 最大值
                zval = zmin; % 起始颜色
                caxis(app.UIAxes_3D,[zmin, zmax./2]); % 设置调整后的颜色映射范围
                % 将起始颜色和第二个颜色更快地对接
                % colormap_value = (cval - cmin) / (cmax - cmin);
                % colormap('jet'); % 使用自定义的 colormap
                set(h_3D, 'FaceColor', 'interp')
                % brighten(0.8)
                
                % 执行任务
                completedTasks = completedTasks + 1;
                % 更新进度条的值
                progressDlg.Value = completedTasks / totalTasks;
                % 暂停一段时间以演示进度条效果
                pause(0.3);
                % 关闭进度条对话框
                close(progressDlg);
                
                % ----------------------------------------------  
                
                %-----------------------------------------【单mat绘图】
                T_length=data_length*app.T;
                t = 1:1*app.T:T_length;          %--X轴时间第一处更改：5ms;    t = 1:5:data_length*5;  
                X_HF=app.data(:,1)';
                mat_data=plot(app.UIAxes1,t,X_HF);
                xlim(app.UIAxes1,[1, T_length]);       %--X轴时间第二处更改：5ms;    xlim([1, data_length*5]); 
                title(app.UIAxes1,'FFT2M');
                xlabel(app.UIAxes1,app.X_danwei);
            
    
                my_30_48=sum(app.data(:,29:47),2);
                X_HF_30_48=my_30_48';
                mat_data_30_48=plot(app.UIAxes2,t,X_HF_30_48);
                xlim(app.UIAxes2,[1, T_length]);
                title(app.UIAxes2,'FFT30-48M');
                xlabel(app.UIAxes2,app.X_danwei);            
    
                h_window=plot(app.UIAxes3,t,one_mat_many_cycle_judge_result,'r');
                xlim(app.UIAxes3,[1, T_length]);
                title(app.UIAxes3,[num2str(total_window),'cycle选',num2str(judge_window),'cycle判别结果']);
                xlabel(app.UIAxes3,app.X_danwei);            
    
                h_cycle=plot(app.UIAxes4,t,one_mat_one_cycle_judge_result,'m');
                xlim(app.UIAxes4,[1, T_length]);
                title(app.UIAxes4,'单cycle判别结果');
                xlabel(app.UIAxes4,app.X_danwei);            
    
                h_basic=plot(app.UIAxes5,t,one_mat_basic_all(:,1)','g');
                xlim(app.UIAxes5,[1, T_length]);
                title(app.UIAxes5,['基准更新次数--2M为例',num2str(basic_update_count)]);
                xlabel(app.UIAxes5,app.X_danwei);            
    
                h1=plot(app.UIAxes6,t,one_mat_judge1);
                xlim(app.UIAxes6,[1, T_length]);
                title(app.UIAxes6,'判据1-固定增值');
                xlabel(app.UIAxes6,app.X_danwei);            
    
                h2=plot(app.UIAxes7,t,one_mat_judge2);
                xlim(app.UIAxes7,[1, T_length]);
                title(app.UIAxes7,'判据2-固定倍数');
                xlabel(app.UIAxes7,app.X_danwei);            
    
                h3=plot(app.UIAxes8,t,one_mat_judge3);
                xlim(app.UIAxes8,[1, T_length]);
                title(app.UIAxes8,'判据3-原值求和');
                xlabel(app.UIAxes8,app.X_danwei);            
    
                h4=plot(app.UIAxes9,t,one_mat_judge4);
                xlim(app.UIAxes9,[1, T_length]);
                title(app.UIAxes9,'判据4-比值求和');
                xlabel(app.UIAxes9,app.X_danwei);            
    
                h5=plot(app.UIAxes10,t,one_mat_judge5);
                xlim(app.UIAxes10,[1, T_length]);
                title(app.UIAxes10,'判据5-电流I');
                xlabel(app.UIAxes10,app.X_danwei);


                % 【单mat图形更新】
                up_dot=floor(linspace(1,length(t),v));
                
                for m = up_dot                   %1:(length(t)-1)/v:length(t)
                    % 更新曲面的Z值
    %                 set(h_3D, 'XData', X(:,1:m),h_3D, 'YData', Y(:,1:m),h_3D, 'ZData', Z(:,1:m));
                    set(h_3D, 'XData', X(:,1:m));
                    set(h_3D, 'YData', Y(:,1:m));
                    set(h_3D, 'ZData', Z(:,1:m));
                    set(mat_data,'XData', t(1:m), 'YData', X_HF(1:m));
                    set(mat_data_30_48,'XData', t(1:m), 'YData', X_HF_30_48(1:m));
                    set(h_window,'XData', t(1:m), 'YData', one_mat_many_cycle_judge_result(1:m));
                    set(h_cycle,'XData', t(1:m), 'YData', one_mat_one_cycle_judge_result(1:m));
                    set(h_basic,'XData', t(1:m), 'YData', one_mat_basic_all(1:m));
                    set(h1,'XData', t(1:m), 'YData', one_mat_judge1(1:m));
                    set(h2,'XData', t(1:m), 'YData', one_mat_judge2(1:m));
                    set(h3,'XData', t(1:m), 'YData', one_mat_judge3(1:m));
                    set(h4,'XData', t(1:m), 'YData', one_mat_judge4(1:m));
                    set(h5,'XData', t(1:m), 'YData', one_mat_judge5(1:m));
                    %                 set(h2, 'YData', x2(1:i));
            %         drawnow;
                    pause(0.0001);  % 控制更新速度
                end
                aa=0;
            catch error
                % 将错误信息写入日志文件
                fid = fopen('error_log758.txt', 'a');
                fprintf(fid, '%s\n', error.message);
                fclose(fid);
            end
        end

        % Button down function: UIAxes11
        function UIAxes11ButtonDown(app, event)
            
        end

        % Button pushed function: Button_jiexi
        function Button_jiexiPushed2(app, event)
            % 显示文件选择对话框以选择.ini配置文件
            try
                [filename, pathname] = uigetfile('*.ini', '选择配置文件');
                
                % 检查用户是否选择了文件
                if isequal(filename, 0)
                    disp('用户取消了选择 .ini 配置文件');
                else
                    % 构建完整的文件路径
                    fullFilePath = fullfile(pathname, filename);

                    % 显示成功导入的消息
                    msg = sprintf('解析配置文件 "%s" 已成功导入', filename);
                    uialert(app.UIFigure, msg, '导入成功', 'Icon', 'success');
                                           
                    % 访问配置项和执行其他操作...
                    app.TextArea_jiexi_ini.Value = fullFilePath;
                    % 配置文件信息
                end
                figure(app.UIFigure);
            catch error
                % 将错误信息写入日志文件
                fid = fopen('error_log4.txt', 'a');
                fprintf(fid, '%s\n', error.message);
                fclose(fid);
            end
        end

        % Button pushed function: Button_txt
        function Button_txtPushed(app, event)

            % 清空多个图形当前的坐标轴
            axesList = [app.UIAxes1, app.UIAxes2, app.UIAxes3, app.UIAxes4, ...
                    app.UIAxes5, app.UIAxes6, app.UIAxes7, app.UIAxes8, ...
                    app.UIAxes9, app.UIAxes10,app.UIAxes11,app.UIAxes_3D];
            b=[];
            for i = 1:numel(axesList)
                cla(axesList(i));
            end
            
            [file, path] = uigetfile('*.txt', '选择txt文件');
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            app.kaiguan = 1;
            app.file_path  = fullfile(path, file);
            app.file_name = file;
            app.TextArea_txt.Value = app.file_path;
            matdata_temp=[];
%             try
            % 加载 .mat 文件,将数据存放进app.data
            file_lujing=app.TextArea_txt.Value;


            figure(app.UIFigure);
    %             try
    %                 b=jiexiexchange(app,file_lujing);
    %                 figure(app.UIFigure);
    %     %             disp(['Loaded: ' app.file_path]);
    %                 a=load(b);
    %             catch error
    %                 fid = fopen('error_log9.txt', 'a');
    %                 fprintf(fid, '%s\n', error.message);
    %                 fclose(fid);
    %             end
    %             as=0;
    %             for j= 2:48
    %                 eval(['temp=a.CH_FFT_',num2str(j),'M;']);
    %                 temp=double((temp'));
    %                 matdata_temp=[matdata_temp,temp];
    %                 % 处理你的操作，例如打印文件名或处理数据
    %             end
            try
                b=jiexiexchange(app,file_lujing);   
            catch error
            % 将错误信息写入日志文件
                fid = fopen('error_log99888.txt', 'a');
                fprintf(fid, '%s\n', error.message);
                fclose(fid);
            end
                disp(b);
                a=0;

                app.data=b;
                    
%                 catch
%                     % 显示错误导入的消息
%                     msg = sprintf('配置文件 "%s" 导入不匹配', file);
%                     uialert(app.UIFigure, msg, '导入不匹配,请重新导入', 'Icon', 'error');
%                 end
%                 figure(app.UIFigure);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3D = uiaxes(app.UIFigure);
            title(app.UIAxes_3D, '动态时序图')
            xlabel(app.UIAxes_3D, '时间/ms')
            ylabel(app.UIAxes_3D, '频率通道/M')
            zlabel(app.UIAxes_3D, '幅值平均值')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '2M')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.ButtonDownFcn = createCallbackFcn(app, @UIAxes1ButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '30-48M求和')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '最终多cycle判别')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '单cycle判别')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, '基准更新-2M为例')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, '判据1-固定增值')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, '判据2-固定倍数')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, '判据3-原值求和')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, '判据4-比值求和')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.UIFigure);
            title(app.UIAxes10, '判据5-电流升降')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.UIFigure);
            title(app.UIAxes11, '2-48M通道normal-dh对比图')
            xlabel(app.UIAxes11, '通道/M')
            ylabel(app.UIAxes11, '幅值平均值')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes11.ButtonDownFcn = createCallbackFcn(app, @UIAxes11ButtonDown, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DX.png';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Button_ini = uibutton(app.Panel, 'push');
            app.Button_ini.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_ini.Text = '选择判别配置文件';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '配置路径';
            app.TextArea_judge_ini = uitextarea(app.Panel);
            app.TextArea_judge_ini.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_Dynamic_judge = uibutton(app.Panel, 'push');
            app.Button_Dynamic_judge.ButtonPushedFcn = createCallbackFcn(app, @Button_Dynamic_judgePushed, true);
            app.Button_Dynamic_judge.Text = '绘制仿软件判别图';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.txtTab = uitab(app.TabGroup);
            app.txtTab.Title = 'txt文件';
            app.Button_jiexi = uibutton(app.txtTab, 'push');
            app.Button_jiexi.ButtonPushedFcn = createCallbackFcn(app, @Button_jiexiPushed2, true);
            app.Button_jiexi.Text = '选择解析配置文件';
            app.Label_3 = uilabel(app.txtTab);
            app.Label_3.Text = '解析路径';
            app.TextArea_jiexi_ini = uitextarea(app.txtTab);
            app.Button_txt = uibutton(app.txtTab, 'push');
            app.Button_txt.ButtonPushedFcn = createCallbackFcn(app, @Button_txtPushed, true);
            app.Button_txt.Text = '选择txt文件';
            app.Label_4 = uilabel(app.txtTab);
            app.Label_4.Text = '文件路径';
            app.TextArea_txt = uitextarea(app.txtTab);
            app.matTab = uitab(app.TabGroup);
            app.matTab.Title = 'mat文件';
            app.Label = uilabel(app.matTab);
            app.Label.Text = '文件路径';
            app.TextArea_mat = uitextarea(app.matTab);
            app.TextArea_mat.ValueChangedFcn = createCallbackFcn(app, @TextArea_matValueChanged, true);
            app.Button_mat = uibutton(app.matTab, 'push');
            app.Button_mat.ButtonPushedFcn = createCallbackFcn(app, @Button_matPushed, true);
            app.Button_mat.Text = '选择mat文件';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynamic_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end